classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        a_xEditFieldLabel_7     matlab.ui.control.Label
        a_xEditFieldLabel_6     matlab.ui.control.Label
        a_xEditFieldLabel_5     matlab.ui.control.Label
        ClearAllButton          matlab.ui.control.Button
        a_xEditFieldLabel_4     matlab.ui.control.Label
        a_xEditFieldLabel_3     matlab.ui.control.Label
        a_xEditFieldLabel_2     matlab.ui.control.Label
        FocalEditField          matlab.ui.control.NumericEditField
        FocalEditFieldLabel     matlab.ui.control.Label
        ViewButton              matlab.ui.control.Button
        a_zEditField            matlab.ui.control.NumericEditField
        a_zEditFieldLabel       matlab.ui.control.Label
        a_yEditField            matlab.ui.control.NumericEditField
        a_yEditFieldLabel       matlab.ui.control.Label
        a_xEditField            matlab.ui.control.NumericEditField
        a_xLabel                matlab.ui.control.Label
        TzEditField             matlab.ui.control.NumericEditField
        TzEditFieldLabel        matlab.ui.control.Label
        TyEditField             matlab.ui.control.NumericEditField
        TyEditFieldLabel        matlab.ui.control.Label
        TxEditField             matlab.ui.control.NumericEditField
        TxEditFieldLabel        matlab.ui.control.Label
        ResetButton             matlab.ui.control.Button
        SaveButton              matlab.ui.control.Button
        DrawvantagepointButton  matlab.ui.control.Button
        Step3SelectviewLabel    matlab.ui.control.Label
        Step2Label              matlab.ui.control.Label
        Step1Label              matlab.ui.control.Label
        DrawbackgroundButton    matlab.ui.control.Button
        UploadimageButton       matlab.ui.control.Button
        UIAxes                  matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %% Used for plotting and gui control
        vantage
        background
        l1
        l2
        l3
        l4
        imsize_x
        imsize_y
        filename
        %% Used in calculations
        vantage_point
        upleft
        upright
        downleft
        downright
        up2left
        up2right
        down2left
        down2right
        %% Used in calcultions
        vertices
        depth
        coordinates3D
        focal_length
        %% Used for testing
        im
        im_rect
        im_rect_resize
        im_out
        Tx
        Ty
        Tz
        a_x
        a_y
        a_z
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.focal_length = 400;
            app.Tx = 0;
            app.Ty = 0;
            app.Tz = 0;
            app.a_x = 0;
            app.a_y = 0;
            app.a_z = 0;
        end

        % Button pushed function: UploadimageButton
        function UploadimageButtonPushed(app, event)
           % Upload and display image functionality
           [app.filename,filepath] = uigetfile({'*.*;*.jpg;*.png;*.bmp;*.oct'}, 'Select File to Open');
           fullname = [filepath, app.filename];
           ImageFile = imread(fullname);
           imagesc(ImageFile,'Parent',app.UIAxes);
           app.im = ImageFile;
           app.imsize_x = size(ImageFile,2);
           app.imsize_y = size(ImageFile,1);
           xlim(app.UIAxes,[0 app.imsize_x])
           ylim(app.UIAxes,[0 app.imsize_y])
           title(app.UIAxes,sprintf("Uploaded Image: %s",app.filename),'interpreter','none')
        end

        % Button pushed function: DrawbackgroundButton
        function DrawbackgroundButtonPushed(app, event)
            % Draw background box
            app.background = drawrectangle(app.UIAxes);
        end

        % Button pushed function: DrawvantagepointButton
        function DrawvantagepointButtonPushed(app, event)
            % Draw vantage point
            app.vantage = drawpoint(app.UIAxes);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            % Loading bar
            bar_save = uiprogressdlg(app.UIFigure,'Title','Creating mesh...','Indeterminate','on');
            drawnow
            
            % Get background box coordinates
            box_coordinates = app.background.Position;
            app.upleft = box_coordinates(1,1:2);
            width = box_coordinates(1,3);
            height = box_coordinates(1,4);
            
            % Get remaining box points
            app.downright = [app.upleft(1,1)+width app.upleft(1,2)+height];
            app.upright = [app.upleft(1,1)+width app.upleft(1,2)];
            app.downleft = [app.upleft(1,1) app.upleft(1,2)+height];
            
            % Draw lines connecting to the vantage point - create mesh
            app.vantage_point = app.vantage.Position;

            %% Draw upright spider mesh line
            a1 = (app.upright(1,2) - app.vantage_point(1,2))/(app.upright(1,1) - app.vantage_point(1,1));
            b1 = app.upright(1,2) - a1*app.upright(1,1);

            % Get up2right point
            app.up2right(1,2) = 0;
            app.up2right(1,1) = (app.up2right(1,2) - b1)/a1;

            if app.up2right(1,1) > app.imsize_x
                app.up2right(1,1) = app.imsize_x;
                app.up2right(1,2) = a1*app.up2right(1,1) + b1;
            end
          
            app.l1 = line(app.UIAxes, [app.vantage_point(1,1) app.upright(1,1); app.upright(1,1) app.up2right(1,1)], ...
                [app.vantage_point(1,2) app.upright(1,2); app.upright(1,2) app.up2right(1,2)],'Color','r','LineStyle','--');
            
            %% Draw upleft spider mesh line
            a2 = (app.upleft(1,2) - app.vantage_point(1,2))/(app.upleft(1,1) - app.vantage_point(1,1));
            b2 = app.upleft(1,2) - a2*app.upleft(1,1);

            % Get up2left point
            app.up2left(1,2) = 0;
            app.up2left(1,1) = (app.up2left(1,2) - b2)/a2;

            if app.up2left(1,1) < 0
                app.up2left(1,1) = 0;
                app.up2left(1,2) = a2*app.up2left(1,1) + b2;
            end

            app.l2 = line(app.UIAxes, [app.vantage_point(1,1) app.upleft(1,1) ; app.upleft(1,1) app.up2left(1,1)],...
                [app.vantage_point(1,2) app.upleft(1,2); app.upleft(1,2) app.up2left(1,2)],'Color','r','LineStyle','--');
            
            %% Draw downleft spider mesh line
            a3 = (app.downleft(1,2) - app.vantage_point(1,2))/(app.downleft(1,1) - app.vantage_point(1,1));
            b3 = app.downleft(1,2) - a3*app.downleft(1,1);

            % Get down2left point
            app.down2left(1,1) = 0;
            app.down2left(1,2) = a3*app.down2left(1,1) + b3;

            if app.down2left(1,2) > app.imsize_y
                app.down2left(1,2) = app.imsize_y;
                app.down2left(1,1) = (app.down2left(1,2) - b3)/a3;
            end

            app.l3 = line(app.UIAxes, [app.vantage_point(1,1) app.downleft(1,1); app.downleft(1,1) app.down2left(1,1)],...
                [app.vantage_point(1,2) app.downleft(1,2) ; app.downleft(1,2) app.down2left(1,2)],'Color','r','LineStyle','--');
            
            %% Draw downright spider mesh line
            a4 = (app.downright(1,2) - app.vantage_point(1,2))/(app.downright(1,1) - app.vantage_point(1,1));
            b4 = app.downright(1,2) - a4*app.downright(1,1);
            
            % Get down2right point
            app.down2right(1,1) = app.imsize_x;
            app.down2right(1,2) = a4*app.down2right(1,1) + b4;

            if app.down2right(1,2) > app.imsize_y
                app.down2right(1,2) = app.imsize_y;
                app.down2right(1,1) = (app.down2right(1,2) - b4)/a4;
            end
            
            app.l4 =line(app.UIAxes, [app.vantage_point(1,1) app.downright(1,1); app.downright(1,1) app.down2right(1,1)],...
                [app.vantage_point(1,2) app.downright(1,2); app.downright(1,2) app.down2right(1,2)],'Color','r','LineStyle','--');
            
            %% Vertices 
            app.vertices = [app.upleft',app.upright',app.downright',app.downleft',...
                app.up2left',app.up2right',app.down2right',app.down2left',app.vantage_point'];
            
            %% Define points and rectify image
            P{1} = [ceil(app.upleft') ceil(app.upright') ceil(app.downright') ceil(app.downleft')];
            P{2} = [ceil(app.up2left') ceil(app.upleft') ceil(app.downleft') ceil(app.down2left')];
            P{3} = [ceil(app.up2left') ceil(app.up2right') ceil(app.upright') ceil(app.upleft')];
            P{4} = [ceil(app.upright') ceil(app.up2right') ceil(app.down2right') ceil(app.downright')];
            P{5} = [ceil(app.downleft') ceil(app.downright') ceil(app.down2right') ceil(app.down2left')];
            app.im_rect = rectify_image(app.im, P);
            
            % close progress bar
            close(bar_save)

        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            types = {'line', 'images.roi.point','images.roi.rectangle'};

            for i = 1:3
                axesHandlesToChildObjects = findobj(app.UIAxes, 'Type', types{i});
                if ~isempty(axesHandlesToChildObjects) 
                    delete(axesHandlesToChildObjects);
                end            
            end
           
           imagesc(app.im,'Parent',app.UIAxes);
           xlim(app.UIAxes,[0 app.imsize_x])
           ylim(app.UIAxes,[0 app.imsize_y])
           title(app.UIAxes,sprintf("Uploaded Image: %s",app.filename),'interpreter','none')

        end

        % Value changed function: TxEditField
        function TxEditFieldValueChanged(app, event)
            value = app.TxEditField.Value;
            % Added value in percentage 
            app.Tx = value*app.imsize_x/100;
        end

        % Value changed function: TyEditField
        function TyEditFieldValueChanged(app, event)
            value = app.TyEditField.Value;
            % Added value in percentage 
            app.Ty = value*app.imsize_y/100;
        end

        % Value changed function: TzEditField
        function TzEditFieldValueChanged(app, event)
            value = app.TzEditField.Value;
            % Added value in percentage 
            app.Tz = value * max(app.depth)/100;
        end

        % Value changed function: a_xEditField
        function a_xEditFieldValueChanged(app, event)
            value = app.a_xEditField.Value;
            % Convert to rad
            app.a_x = (3.1415/180)*value;
        end

        % Value changed function: a_yEditField
        function a_yEditFieldValueChanged(app, event)
            value = app.a_yEditField.Value;
            % Convert to rad
            app.a_y = (3.1415/180)*value;
        end

        % Value changed function: a_zEditField
        function a_zEditFieldValueChanged(app, event)
            value = app.a_zEditField.Value;
            % Convert to rad
            app.a_z = (3.1415/180)*value;
        end

        % Button pushed function: ViewButton
        function ViewButtonPushed(app, event)
           % Loading bar
           bar_view = uiprogressdlg(app.UIFigure,'Title','Reprojecting form 3D model...','Indeterminate','on');
           drawnow
           
            %% create3Dmodel and create3Dplanes
           [d_left, d_top, d_right, d_bottom, app.coordinates3D] = create3Dmodel(app.im, app.focal_length, app.vertices);
           app.depth = [d_left, d_top, d_right, d_bottom];
           app.im_rect_resize = create3Dplanes(app.im ,app.im_rect, app.depth);

           %% Code for reprojection
           rotation_angles = [app.a_x, app.a_y, app.a_z];
           translation = [app.Tx, app.Ty, app.Tz];
           app.im_out = projection_to_2D(app.im_rect_resize, app.depth, app.vantage_point, app.focal_length, rotation_angles, translation);
           % Visualize Transformed Image
           imagesc(app.im_out,'Parent',app.UIAxes);
           
           imoutsize_x = size(app.im_out,2);
           imoutsize_y = size(app.im_out,1);
           xlim(app.UIAxes,[0 imoutsize_x])
           ylim(app.UIAxes,[0 imoutsize_y])
           title(app.UIAxes,sprintf("Transformed Image"),'interpreter','none')

            % Close progress bar
            close(bar_view)
        end

        % Value changed function: FocalEditField
        function FocalEditFieldValueChanged(app, event)
            value = app.FocalEditField.Value;
            app.focal_length = value;
        end

        % Button pushed function: ClearAllButton
        function ClearAllButtonPushed(app, event)
          types = {'line', 'images.roi.point','images.roi.rectangle','image'};

            for i = 1:4
                axesHandlesToChildObjects = findobj(app.UIAxes, 'Type', types{i});
                if ~isempty(axesHandlesToChildObjects) 
                    delete(axesHandlesToChildObjects);
                end            
            end
            title(app.UIAxes,"Choose an image to start your journey...")

        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Choose an image to start your jouney... ')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UploadimageButton = uibutton(app.UIFigure, 'push');
            app.UploadimageButton.ButtonPushedFcn = createCallbackFcn(app, @UploadimageButtonPushed, true);
            app.UploadimageButton.Text = 'Upload image';
            app.DrawbackgroundButton = uibutton(app.UIFigure, 'push');
            app.DrawbackgroundButton.ButtonPushedFcn = createCallbackFcn(app, @DrawbackgroundButtonPushed, true);
            app.DrawbackgroundButton.Text = 'Draw background';
            app.Step1Label = uilabel(app.UIFigure);
            app.Step1Label.Text = 'Step 1';
            app.Step2Label = uilabel(app.UIFigure);
            app.Step2Label.Text = 'Step 2 ';
            app.Step3SelectviewLabel = uilabel(app.UIFigure);
            app.Step3SelectviewLabel.Text = 'Step 3: Select view';
            app.DrawvantagepointButton = uibutton(app.UIFigure, 'push');
            app.DrawvantagepointButton.ButtonPushedFcn = createCallbackFcn(app, @DrawvantagepointButtonPushed, true);
            app.DrawvantagepointButton.Text = 'Draw vantage point';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset ';
            app.TxEditFieldLabel = uilabel(app.UIFigure);
            app.TxEditFieldLabel.Text = 'Tx';
            app.TxEditField = uieditfield(app.UIFigure, 'numeric');
            app.TxEditField.Limits = [-200 200];
            app.TxEditField.ValueChangedFcn = createCallbackFcn(app, @TxEditFieldValueChanged, true);
            app.TyEditFieldLabel = uilabel(app.UIFigure);
            app.TyEditFieldLabel.Text = 'Ty';
            app.TyEditField = uieditfield(app.UIFigure, 'numeric');
            app.TyEditField.Limits = [-200 200];
            app.TyEditField.ValueChangedFcn = createCallbackFcn(app, @TyEditFieldValueChanged, true);
            app.TzEditFieldLabel = uilabel(app.UIFigure);
            app.TzEditFieldLabel.Text = 'Tz';
            app.TzEditField = uieditfield(app.UIFigure, 'numeric');
            app.TzEditField.Limits = [-200 100];
            app.TzEditField.ValueChangedFcn = createCallbackFcn(app, @TzEditFieldValueChanged, true);
            app.a_xLabel = uilabel(app.UIFigure);
            app.a_xLabel.Text = 'a_x ';
            app.a_xEditField = uieditfield(app.UIFigure, 'numeric');
            app.a_xEditField.Limits = [-45 45];
            app.a_xEditField.ValueChangedFcn = createCallbackFcn(app, @a_xEditFieldValueChanged, true);
            app.a_yEditFieldLabel = uilabel(app.UIFigure);
            app.a_yEditFieldLabel.Text = 'a_y';
            app.a_yEditField = uieditfield(app.UIFigure, 'numeric');
            app.a_yEditField.Limits = [-45 45];
            app.a_yEditField.ValueChangedFcn = createCallbackFcn(app, @a_yEditFieldValueChanged, true);
            app.a_zEditFieldLabel = uilabel(app.UIFigure);
            app.a_zEditFieldLabel.Text = 'a_z';
            app.a_zEditField = uieditfield(app.UIFigure, 'numeric');
            app.a_zEditField.Limits = [-45 45];
            app.a_zEditField.ValueChangedFcn = createCallbackFcn(app, @a_zEditFieldValueChanged, true);
            app.ViewButton = uibutton(app.UIFigure, 'push');
            app.ViewButton.ButtonPushedFcn = createCallbackFcn(app, @ViewButtonPushed, true);
            app.ViewButton.Text = 'View';
            app.FocalEditFieldLabel = uilabel(app.UIFigure);
            app.FocalEditFieldLabel.Text = 'Focal';
            app.FocalEditField = uieditfield(app.UIFigure, 'numeric');
            app.FocalEditField.Limits = [1 1000];
            app.FocalEditField.ValueChangedFcn = createCallbackFcn(app, @FocalEditFieldValueChanged, true);
            app.FocalEditField.Value = 400;
            app.a_xEditFieldLabel_2 = uilabel(app.UIFigure);
            app.a_xEditFieldLabel_2.Text = '°';
            app.a_xEditFieldLabel_3 = uilabel(app.UIFigure);
            app.a_xEditFieldLabel_3.Text = '°';
            app.a_xEditFieldLabel_4 = uilabel(app.UIFigure);
            app.a_xEditFieldLabel_4.Text = '°';
            app.ClearAllButton = uibutton(app.UIFigure, 'push');
            app.ClearAllButton.ButtonPushedFcn = createCallbackFcn(app, @ClearAllButtonPushed, true);
            app.ClearAllButton.Text = 'Clear All';
            app.a_xEditFieldLabel_5 = uilabel(app.UIFigure);
            app.a_xEditFieldLabel_5.Text = '%';
            app.a_xEditFieldLabel_6 = uilabel(app.UIFigure);
            app.a_xEditFieldLabel_6.Text = '%';
            app.a_xEditFieldLabel_7 = uilabel(app.UIFigure);
            app.a_xEditFieldLabel_7.Text = '%';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end